classdef MRDreco < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MRDRecoUIFigure             matlab.ui.Figure
        DashboardPanel              matlab.ui.container.Panel
        LoadMRDButton               matlab.ui.control.Button
        Lamp1                       matlab.ui.control.Lamp
        ReconstructButton           matlab.ui.control.Button
        Lamp2                       matlab.ui.control.Lamp
        ExportDicomButton           matlab.ui.control.Button
        Lamp3                       matlab.ui.control.Lamp
        ExitButton                  matlab.ui.control.Button
        RecodimensionsPanel         matlab.ui.container.Panel
        XEditFieldLabel             matlab.ui.control.Label
        XEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        NREditFieldLabel_2          matlab.ui.control.Label
        NREditField                 matlab.ui.control.NumericEditField
        RecoregularizationPanel     matlab.ui.container.Panel
        TVxyzEditFieldLabel         matlab.ui.control.Label
        TVxyzEditField              matlab.ui.control.NumericEditField
        TVtimeEditFieldLabel        matlab.ui.control.Label
        TVtimeEditField             matlab.ui.control.NumericEditField
        WVxyzEditFieldLabel         matlab.ui.control.Label
        WVxyzEditField              matlab.ui.control.NumericEditField
        KspacePanel                 matlab.ui.container.Panel
        KspaceFig                   matlab.ui.control.UIAxes
        XLabel                      matlab.ui.control.Label
        KSliceEditField             matlab.ui.control.NumericEditField
        KSliceSlider                matlab.ui.control.Slider
        KEchoSlider                 matlab.ui.control.Slider
        EchoLabel                   matlab.ui.control.Label
        KEchoEditField              matlab.ui.control.NumericEditField
        KRepetitionSlider           matlab.ui.control.Slider
        RepetitionEditFieldLabel    matlab.ui.control.Label
        KRepetitionEditField        matlab.ui.control.NumericEditField
        OrientationSpinnerLabel     matlab.ui.control.Label
        KOrientationSpinner         matlab.ui.control.Spinner
        KxLabel                     matlab.ui.control.Label
        KyLabel                     matlab.ui.control.Label
        KFlipAngleSlider            matlab.ui.control.Slider
        FlipangleLabel              matlab.ui.control.Label
        KFlipAngleEditField         matlab.ui.control.NumericEditField
        ButtonGroup                 matlab.ui.container.ButtonGroup
        fillingButton               matlab.ui.control.RadioButton
        averagesButton              matlab.ui.control.RadioButton
        kvaluesButton               matlab.ui.control.RadioButton
        ScanparametersPanel         matlab.ui.container.Panel
        NREditFieldLabel            matlab.ui.control.Label
        NRViewField                 matlab.ui.control.NumericEditField
        NEEditFieldLabel            matlab.ui.control.Label
        NEViewField                 matlab.ui.control.NumericEditField
        MatrixViewField2            matlab.ui.control.NumericEditField
        MatrixViewField3            matlab.ui.control.NumericEditField
        FOVViewField2               matlab.ui.control.NumericEditField
        FOVViewField3               matlab.ui.control.NumericEditField
        MXLabel                     matlab.ui.control.Label
        MYLabel                     matlab.ui.control.Label
        MZLabel                     matlab.ui.control.Label
        MatrixEditFieldLabel        matlab.ui.control.Label
        MatrixViewField1            matlab.ui.control.NumericEditField
        FOVmmLabel                  matlab.ui.control.Label
        FOVViewField1               matlab.ui.control.NumericEditField
        TRmsLabel                   matlab.ui.control.Label
        TRViewField                 matlab.ui.control.NumericEditField
        TEmsLabel                   matlab.ui.control.Label
        TEViewField                 matlab.ui.control.NumericEditField
        NALabel                     matlab.ui.control.Label
        NAViewField                 matlab.ui.control.NumericEditField
        NFALabel                    matlab.ui.control.Label
        NFAViewField                matlab.ui.control.NumericEditField
        FAdegEditFieldLabel         matlab.ui.control.Label
        FAViewField                 matlab.ui.control.EditField
        SequenceEditFieldLabel      matlab.ui.control.Label
        SequenceViewField           matlab.ui.control.EditField
        MRDfileLabel                matlab.ui.control.Label
        MRDfileViewField            matlab.ui.control.EditField
        MessagesPanel               matlab.ui.container.Panel
        MessageBox                  matlab.ui.control.TextArea
        ReconstructionPanel         matlab.ui.container.Panel
        RecoFig                     matlab.ui.control.UIAxes
        SliceSlider                 matlab.ui.control.Slider
        EchoSlider                  matlab.ui.control.Slider
        RepetitionSlider            matlab.ui.control.Slider
        XLabel_2                    matlab.ui.control.Label
        SliceEditField              matlab.ui.control.NumericEditField
        EchoLabel_2                 matlab.ui.control.Label
        EchoEditField               matlab.ui.control.NumericEditField
        RepetitionEditFieldLabel_2  matlab.ui.control.Label
        RepetitionEditField         matlab.ui.control.NumericEditField
        OrientationSpinnerLabel_2   matlab.ui.control.Label
        OrientationSpinner          matlab.ui.control.Spinner
        RXLabel                     matlab.ui.control.Label
        RYLabel                     matlab.ui.control.Label
        TitleLabel                  matlab.ui.control.Label
        TulpImage                   matlab.ui.control.Image
    end


    % ------------------------------------------------------
    %
    % MRDreco
    %
    %
    % Gustav Strijkers
    % Amsterdam UMC
    % g.j.strijkers@amsterdamumc.nl
    %
    %
    % Version 1.0
    %
    % - initial commit
    %
    %
    %
    %


    properties (Access = private)
        
        appdir                  % Application directory
        olddir                  % Starting directory
        bart_detected           % Bart toolbox detected true or false
        mrd_import_path         % MRD import path
        dicom_export_path       % Dicom export path
        valid_file              % Valid data file true or false
        valid_reco              % Valid reconstruction true or false
        more_echoes             % More than 1 echo true or false
        more_repetitions        % More than 1 repetition true or false
        more_flipangles         % More than 1 flip angle true or false
        user_kspace_traj        % User k-spae trajectory true or false
        threeDdata              % 3D data yes or no
        seqpar                  % Sequence parameters
        flipangles              % Flip angle array
        kspace                  % Raw k-space data
        nsa_space               % Nubmer of signal averages per k-point
        filling_space           % K-space points acquired (1) or not (0)
        image_orient            % image orientation permutation matrix
        kspace_orient_label
        image_orient_label
        
    end


    methods (Access = private)
        
        
        % ------------ Light and buttons --------------------
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.ReconstructButton.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.ExportDicomButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.ExitButton.Enable = code{4}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadMRDButton.Enable;
            result{2} = app.ReconstructButton.Enable;
            result{3} = app.ExportDicomButton.Enable;
            result{4} = app.ExitButton.Enable;
            
        end


        % ------------ Parameters --------------------
        
        function SetParametersFcn(app)
            
            app.TextMessage('Setting k-space parameters ....');
            
            % 2D or 3D data
            if app.seqpar.NO_VIEWS_2>1
                
                app.TextMessage('3D data ...');
                app.threeDdata = true;
                
            else
                
                app.TextMessage('2D multi-slice data ...');
                app.threeDdata = false;
                
            end
            
            % More than 1 flip angle
            app.flipangles = app.seqpar.alpha;
            if isfield(app.seqpar, 'VFA_size')
                
                if app.seqpar.VFA_size > 0
                    
                    app.TextMessage('Variable flip-angle data detected ...');
                    app.more_flipangles = true;
                    app.flipangles = app.seqpar.VFA_angles(1:app.seqpar.VFA_size);
                    
                end
                
            end
            
            % More than 1 echo
            if app.seqpar.NO_ECHOES > 1
                
                app.TextMessage('Multi-echo data detected ...');
                app.more_echoes = true;
                
            end
            
            % More than 1 repetition
            if app.seqpar.EXPERIMENT_ARRAY>1
                
                if app.more_flipangles
                    app.seqpar.EXPERIMENT_ARRAY = app.seqpar.EXPERIMENT_ARRAY/app.seqpar.VFA_size;
                end
                
                if app.seqpar.EXPERIMENT_ARRAY>1
                    app.TextMessage('Multiple repetitions detected ...');
                    app.more_repetitions = true;
                end
                
            end
            
            % User k-space trajectory
            if strcmpi(app.seqpar.PPL,'flash_me_presat_chess_btgec614b.ppl')
                
                app.user_kspace_traj = true;
                app.TextMessage('User k-space trajectory ...');
                app.TextMessage('Not implemented yet ...');
                
            end
            
            % Show some parameters in app
            app.SequenceViewField.Value = app.seqpar.PPL;
            app.TRViewField.Value = app.seqpar.tr;
            app.TEViewField.Value = app.seqpar.te;
            app.NAViewField.Value = app.seqpar.NO_AVERAGES;
            app.NRViewField.Value = app.seqpar.EXPERIMENT_ARRAY;
            app.NEViewField.Value = app.seqpar.NO_ECHOES;
            app.FAViewField.Value = ['  ',num2str(app.flipangles)];
            app.NFAViewField.Value = length(app.flipangles);
            
            % Matrix size
            if app.threeDdata
                
                % 3D data
                app.MZLabel.Text = 'Z';
                app.ZEditFieldLabel.Text = 'Z';
                
                % Matrix dimensions
                app.MatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.MatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.MatrixViewField3.Value = app.seqpar.NO_VIEWS_2;
                
                % Permute data to (X, Y, Z, other)
                if ndims(app.kspace)==3 app.kspace = permute(app.kspace,[3,2,1]); end
                if ndims(app.kspace)==4 app.kspace = permute(app.kspace,[4,3,2,1]); end
                if ndims(app.kspace)==5 app.kspace = permute(app.kspace,[5,4,3,1,2]); end
                if ndims(app.kspace)==6 app.kspace = permute(app.kspace,[6,5,4,1,2,3]); end
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.SMY/app.seqpar.SMX;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
                
            else
                
                % 2D data
                app.MZLabel.Text = 'Slices';
                app.ZEditFieldLabel.Text = 'Slices';
                
                % Matrix dimensions
                app.MatrixViewField1.Value = app.seqpar.NO_SAMPLES;
                app.MatrixViewField2.Value = app.seqpar.NO_VIEWS;
                app.MatrixViewField3.Value = app.seqpar.NO_SLICES;
                
                % Permute data to (X, Y, slices, other)
                if ndims(app.kspace)==3 app.kspace = permute(app.kspace,[2,3,1]); end
                if ndims(app.kspace)==4 app.kspace = permute(app.kspace,[3,4,2,1]); end
                if ndims(app.kspace)==5 app.kspace = permute(app.kspace,[4,5,3,1,2]); end
                if ndims(app.kspace)==6 app.kspace = permute(app.kspace,[5,6,4,1,2,3]); end
                
                % Field of view
                app.FOVViewField1.Value = app.seqpar.FOV;
                app.FOVViewField2.Value = app.seqpar.FOV*app.seqpar.SMX/app.seqpar.SMY;
                app.FOVViewField3.Value = app.seqpar.SLICE_THICKNESS;
                
            end
            
            % Permute data to (X, Y, Z, NR, NFA, NE)    ---- NOT FULLY TESTED -----
            if ndims(app.kspace)==4 && app.more_flipangles app.kspace = permute(app.kspace,[1,2,3,5,4,6]); end
            if ndims(app.kspace)==4 && app.more_echoes app.kspace = permute(app.kspace,[1,2,3,5,6,4]); end
            if ndims(app.kspace)==5 & app.more_echoes & app.more_flipangles app.kspace = permute(app.kspace,[1,2,3,6,4,5]); end
            if ndims(app.kspace)==5 & app.more_echoes & app.more_repetitions app.kspace = permute(app.kspace,[1,2,3,4,6,5]); end
            if ndims(app.kspace)==5 & app.more_repetitions & app.more_flipangles app.kspace = permute(app.kspace,[1,2,3,4,5,6]); end
            
            % Reco size
            app.XEditField.Value = app.MatrixViewField1.Value;
            app.YEditField.Value = app.MatrixViewField2.Value;
            app.ZEditField.Value = app.MatrixViewField3.Value;
            app.NREditField.Value = app.NRViewField.Value;
            
            % Image orientations
            app.image_orient = [3 1 2 4 5 6; 2 1 3 4 5 6 ; 1 2 3 4 5 6 ; 4 3 1 2 5 6 ; 4 2 1 3 5 6 ; 4 1 2 3 5 6];
            app.kspace_orient_label = ['Kz','Kx','Ky','R'; 'Ky','Kx','Kz','R'; 'Kx','Ky','Kz','R'; 'R','Kz','Kx','Ky' ; 'R','Ky','Kx','Kz' ; 'R','Kx','Ky','Kz'];
            app.image_orient_label = ['Z','X','Y','R'; 'Y','X','Z','R'; 'X','Y','Z','R'; 'R','Z','X','Y' ; 'R','Y','X','Z' ; 'R','X','Y','Z'];
            
            
        end % SetParametersFcn


        function SetKspaceLimitsFcn(app)
        
            app.TextMessage('Setting k-space parameter limits ....');
            
            app.KSliceEditField.Limits = [1,app.MatrixViewField1.Value];
            app.KSliceSlider.Limits = [1,app.MatrixViewField1.Value];
            app.KSliceEditField.Value = round(app.MatrixViewField1.Value/2);
            app.KSliceSlider.Value = round(app.MatrixViewField1.Value/2);
        
            % Echo selection
            app.KEchoEditField.Value = 1;
            app.KEchoSlider.Value = 1;
            if app.more_echoes
                app.KEchoEditField.Limits = [1,app.NEViewField.Value];
                app.KEchoSlider.Limits = [1,app.NEViewField.Value];
            else
                app.KEchoEditField.Limits = [1,2];
                app.KEchoSlider.Limits = [1,2];
            end
            
            % Flip angle selection
            app.KFlipAngleEditField.Value = 1;
            app.KFlipAngleSlider.Value = 1;
            if app.more_flipangles
                app.KFlipAngleEditField.Limits = [1,app.NFAViewField.Value];
                app.KFlipAngleSlider.Limits = [1,app.NFAViewField.Value];
            else
                app.KFlipAngleEditField.Limits = [1,2];
                app.KFlipAngleSlider.Limits = [1,2];
            end
            
            % Repetitions selection
            app.KRepetitionEditField.Value = 1;
            app.KRepetitionSlider.Value = 1;
            if app.more_repetitions
                app.KRepetitionEditField.Limits = [1,app.NRViewField.Value];
                app.KRepetitionSlider.Limits = [1,app.NRViewField.Value];
            else
                app.KRepetitionEditField.Limits = [1,2];
                app.KRepetitionSlider.Limits = [1,2];
            end
            
        end % SetKspaceLimitsFcn


        function FillKspaceFcn(app)
        
         app.TextMessage('Filling k-space ....');
        
            if app.valid_file
                
                if ~app.user_kspace_traj
                    
                    app.nsa_space = app.seqpar.NO_AVERAGES * ones(size(app.kspace));
                    app.filling_space = ones(size(app.kspace));
                    
                else
                    
                    % not implemented yet
                    
                end
            
        
            end
            
        end % FillKspaceFcn


        % ------------ Figures --------------------
        
              
        function SetOrientationsFcn(app)
        
            %
        
        
        end


        function PlotKspaceDataFig(app)
            
            cla(app.KspaceFig);
            hold(app.KspaceFig,'on');
            
            if app.valid_file
                
                % Select the data
                if app.fillingButton.Value == 1 im = app.filling_space; cmap = summer; end
                if app.averagesButton.Value == 1 im = app.nsa_space; cmap = parula; end
                if app.kvaluesButton.Value == 1 im = abs(app.kspace); cmap = gray; end
                
                % Select which slice to show
                im = permute(im,app.image_orient(app.KOrientationSpinner.Value,:));
                im = squeeze(im(app.KSliceEditField.Value,:,:,app.KRepetitionEditField.Value,app.KFlipAngleEditField.Value,app.KEchoEditField.Value));
                
                % Label the orientations
                app.KSliceEditField.Label = app.kspace_orient_label(app.KOrientationSpinner.Value,1);
                app.KxLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,2);
                app.KyLabel.Text = app.kspace_orient_label(app.KOrientationSpinner.Value,3);
                
                % Plot the image in the k-space images panel
                app.KspaceFig.Position = [34,39,325,325];
                xlim(app.KspaceFig,[0, size(im,1) + 1]);
                ylim(app.KspaceFig,[0, size(im,2) + 1]);
                imshow(rot90(im),[0 max(im(:))],'ColorMap',cmap,'InitialMagnification','fit','Parent',app.KspaceFig);
                
            end
             
            hold(app.KspaceFig,'off');
            drawnow;
            
        end % PlotKspaceDataFig


    end


    % ------------ Public functions --------------------
    
    methods (Access = public)
        
        function TextMessage(app, message)
            
            % Text messages in the app
            app.MessageBox.Value = [message ; app.MessageBox.Value];
            drawnow;
            
        end % TextMessage
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % empty file import and export paths
            app.mrd_import_path = '';
            app.dicom_export_path = '';
            
            % start the parallel pool if it is not running already
            % p = gcp('nocreate');
            % if isempty(p)
            %    parpool;
            % end
            
            % add subfolders to search path
            if (~isdeployed)
                app.olddir = pwd;
                info = dir(which('MRDreco.mlapp'));
                app.appdir = info.folder;
                cd(app.appdir);
                addpath(genpath(pwd));
            end
            
            % add the bart path to the environment variable if available
            app.bart_detected = false;
            if isfile('bartpath.txt')
                app.bart_detected = true;
                app.TextMessage('BART toolbox detected ...');
                setenv('TOOLBOX_PATH', fileread('bartpath.txt'));
            end
            
            app.TulpImage.ImageSource = [app.appdir,filesep,'images',filesep,'AmsterdamUMC.gif'];
            
            % suppress unneccesary warnings
            warning off;
            
        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
            % Select 1 MRD file which contains multiple flip-angles data
            app.MRDRecoUIFigure.Visible = 'off';
            [mrdfile,app.mrd_import_path] = uigetfile([app.mrd_import_path,'/*.MRD']);
            app.MRDRecoUIFigure.Visible = 'on';
            
            % Initial settings
            app.valid_file = false;
            app.valid_reco = false;
            app.threeDdata = false;
            app.user_kspace_traj = false;
            app.more_echoes = false;
            app.more_repetitions = false;
            app.more_flipangles = false;
            
            if ischar(mrdfile)
                
                % MRD file selected
                app.MRDfileViewField.Value = mrdfile;
                mrdfile = [app.mrd_import_path,mrdfile];
                
                % Load the data
                app.TextMessage('Reading MRD file ...');
                [kdata,~,app.seqpar] = Get_mrd_3D4(mrdfile,'seq','cen');
                app.valid_file = true;
                app.kspace = kdata;
                
                % Determine the sequence file
                if ismac
                    app.seqpar.PPL = replace(app.seqpar.PPL,'\','/');
                end
                [~,name,ext] = fileparts(app.seqpar.PPL);
                app.seqpar.PPL = strcat(name,ext);
                
            end
            
            % Report on valid/invalid file selection
            if app.valid_file
                
                % Set the buttons for valid file
                
                % Set other parameters
                app.SetParametersFcn;
                app.SetKspaceLimitsFcn;
                app.FillKspaceFcn;
            
                app.TextMessage('Data import ready ...');
                app.TextMessage(strcat('Size of k-space =',{' '},num2str(size(app.kspace))));
                
                
                app.PlotKspaceDataFig;
          
            else                
                
                % Not a valid data file 
                uialert(app.MRDRecoUIFigure,'Please select a valid data file','Error');
                
                % Set the buttons for invalid data file 
                
            end
            
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)


        end

        % Button pushed function: ExportDicomButton
        function ExportDicomButtonPushed(app, event)
            
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search path
            if (~isdeployed)
                info = dir(which('MRDreco.mlapp'));
                app.appdir = info.folder;
                rmpath(genpath(app.appdir));
                cd(app.olddir);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MRDRecoUIFigure = uifigure('Visible', 'off');
            app.DashboardPanel = uipanel(app.MRDRecoUIFigure);
            app.DashboardPanel.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardPanel, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Text = 'Load MRD';
            app.Lamp1 = uilamp(app.DashboardPanel);
            app.ReconstructButton = uibutton(app.DashboardPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Text = 'Reconstruct';
            app.Lamp2 = uilamp(app.DashboardPanel);
            app.ExportDicomButton = uibutton(app.DashboardPanel, 'push');
            app.ExportDicomButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDicomButtonPushed, true);
            app.ExportDicomButton.Text = 'Export Dicom';
            app.Lamp3 = uilamp(app.DashboardPanel);
            app.ExitButton = uibutton(app.DashboardPanel, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RecodimensionsPanel = uipanel(app.DashboardPanel);
            app.RecodimensionsPanel.Title = 'Reco dimensions';
            app.XEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.XEditField.Limits = [16 1024];
            app.XEditField.RoundFractionalValues = 'on';
            app.XEditField.ValueDisplayFormat = '%.0f';
            app.XEditField.Value = 128;
            app.YEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.YEditField.Limits = [16 1024];
            app.YEditField.RoundFractionalValues = 'on';
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = 128;
            app.ZEditFieldLabel = uilabel(app.RecodimensionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.ZEditField.Limits = [1 1024];
            app.ZEditField.RoundFractionalValues = 'on';
            app.ZEditField.ValueDisplayFormat = '%.0f';
            app.ZEditField.Value = 128;
            app.NREditFieldLabel_2 = uilabel(app.RecodimensionsPanel);
            app.NREditFieldLabel_2.Text = 'NR';
            app.NREditField = uieditfield(app.RecodimensionsPanel, 'numeric');
            app.NREditField.Limits = [1 1024];
            app.NREditField.RoundFractionalValues = 'on';
            app.NREditField.ValueDisplayFormat = '%.0f';
            app.NREditField.Value = 1;
            app.RecoregularizationPanel = uipanel(app.DashboardPanel);
            app.RecoregularizationPanel.Title = 'Reco regularization';
            app.TVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVxyzEditFieldLabel.Text = 'TVxyz';
            app.TVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVxyzEditField.Limits = [0 2];
            app.TVxyzEditField.Value = 0.01;
            app.TVtimeEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.TVtimeEditFieldLabel.Text = 'TVtime';
            app.TVtimeEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.TVtimeEditField.Limits = [0 2];
            app.TVtimeEditField.Value = 0.01;
            app.WVxyzEditFieldLabel = uilabel(app.RecoregularizationPanel);
            app.WVxyzEditFieldLabel.Text = 'WVxyz';
            app.WVxyzEditField = uieditfield(app.RecoregularizationPanel, 'numeric');
            app.WVxyzEditField.Limits = [0 2];
            app.WVxyzEditField.Value = 0.01;
            app.KspacePanel = uipanel(app.MRDRecoUIFigure);
            app.KspacePanel.Title = 'K-space';
            app.KspaceFig = uiaxes(app.KspacePanel);
            title(app.KspaceFig, '')
            xlabel(app.KspaceFig, '')
            ylabel(app.KspaceFig, '')
            app.KspaceFig.XTick = [];
            app.KspaceFig.YTick = [];
            app.XLabel = uilabel(app.KspacePanel);
            app.XLabel.Text = 'X';
            app.KSliceEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KSliceEditField.Limits = [1 Inf];
            app.KSliceEditField.ValueDisplayFormat = '%.0f';
            app.KSliceEditField.Value = 1;
            app.KSliceSlider = uislider(app.KspacePanel);
            app.KSliceSlider.Limits = [1 200];
            app.KSliceSlider.MajorTicks = [];
            app.KSliceSlider.MinorTicks = [];
            app.KSliceSlider.Value = 1;
            app.KEchoSlider = uislider(app.KspacePanel);
            app.KEchoSlider.Limits = [1 200];
            app.KEchoSlider.MajorTicks = [];
            app.KEchoSlider.MinorTicks = [];
            app.KEchoSlider.Value = 1;
            app.EchoLabel = uilabel(app.KspacePanel);
            app.EchoLabel.Text = 'Echo';
            app.KEchoEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KEchoEditField.Limits = [1 Inf];
            app.KEchoEditField.ValueDisplayFormat = '%.0f';
            app.KEchoEditField.Value = 1;
            app.KRepetitionSlider = uislider(app.KspacePanel);
            app.KRepetitionSlider.Limits = [1 200];
            app.KRepetitionSlider.MajorTicks = [];
            app.KRepetitionSlider.MinorTicks = [];
            app.KRepetitionSlider.Value = 1;
            app.RepetitionEditFieldLabel = uilabel(app.KspacePanel);
            app.RepetitionEditFieldLabel.Text = 'Repetition';
            app.KRepetitionEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KRepetitionEditField.Limits = [1 Inf];
            app.KRepetitionEditField.ValueDisplayFormat = '%.0f';
            app.KRepetitionEditField.Value = 1;
            app.OrientationSpinnerLabel = uilabel(app.KspacePanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.KOrientationSpinner = uispinner(app.KspacePanel);
            app.KOrientationSpinner.Limits = [1 6];
            app.KOrientationSpinner.Value = 1;
            app.KxLabel = uilabel(app.KspacePanel);
            app.KxLabel.Text = 'Kx';
            app.KyLabel = uilabel(app.KspacePanel);
            app.KyLabel.Text = 'Ky';
            app.KFlipAngleSlider = uislider(app.KspacePanel);
            app.KFlipAngleSlider.Limits = [1 200];
            app.KFlipAngleSlider.MajorTicks = [];
            app.KFlipAngleSlider.MinorTicks = [];
            app.KFlipAngleSlider.Value = 1;
            app.FlipangleLabel = uilabel(app.KspacePanel);
            app.FlipangleLabel.Text = 'Flip angle';
            app.KFlipAngleEditField = uieditfield(app.KspacePanel, 'numeric');
            app.KFlipAngleEditField.Limits = [1 Inf];
            app.KFlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.KFlipAngleEditField.Value = 1;
            app.ButtonGroup = uibuttongroup(app.KspacePanel);
            app.fillingButton = uiradiobutton(app.ButtonGroup);
            app.fillingButton.Text = 'filling';
            app.fillingButton.Value = true;
            app.averagesButton = uiradiobutton(app.ButtonGroup);
            app.averagesButton.Text = 'averages';
            app.kvaluesButton = uiradiobutton(app.ButtonGroup);
            app.kvaluesButton.Text = '| k-values |';
            app.ScanparametersPanel = uipanel(app.MRDRecoUIFigure);
            app.ScanparametersPanel.Title = 'Scan parameters';
            app.NREditFieldLabel = uilabel(app.ScanparametersPanel);
            app.NREditFieldLabel.Text = 'NR';
            app.NRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NRViewField.Editable = 'off';
            app.NEEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.NEEditFieldLabel.Text = 'NE';
            app.NEViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NEViewField.Editable = 'off';
            app.MatrixViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.MatrixViewField2.Limits = [1 Inf];
            app.MatrixViewField2.ValueDisplayFormat = '%.0f';
            app.MatrixViewField2.Editable = 'off';
            app.MatrixViewField2.Value = 1;
            app.MatrixViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.MatrixViewField3.Limits = [1 Inf];
            app.MatrixViewField3.ValueDisplayFormat = '%.0f';
            app.MatrixViewField3.Editable = 'off';
            app.MatrixViewField3.Value = 1;
            app.FOVViewField2 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField3 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField3.Limits = [0 Inf];
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.MXLabel = uilabel(app.ScanparametersPanel);
            app.MXLabel.Text = 'X';
            app.MYLabel = uilabel(app.ScanparametersPanel);
            app.MYLabel.Text = 'Y';
            app.MZLabel = uilabel(app.ScanparametersPanel);
            app.MZLabel.Text = 'Z';
            app.MatrixEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.MatrixEditFieldLabel.Text = 'Matrix';
            app.MatrixViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.MatrixViewField1.Limits = [1 Inf];
            app.MatrixViewField1.ValueDisplayFormat = '%.0f';
            app.MatrixViewField1.Editable = 'off';
            app.MatrixViewField1.Value = 1;
            app.FOVmmLabel = uilabel(app.ScanparametersPanel);
            app.FOVmmLabel.Text = 'FOV (mm)';
            app.FOVViewField1 = uieditfield(app.ScanparametersPanel, 'numeric');
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.TRmsLabel = uilabel(app.ScanparametersPanel);
            app.TRmsLabel.Text = 'TR (ms)';
            app.TRViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TEmsLabel = uilabel(app.ScanparametersPanel);
            app.TEmsLabel.Text = 'TE (ms)';
            app.TEViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.TEViewField.Editable = 'off';
            app.NALabel = uilabel(app.ScanparametersPanel);
            app.NALabel.Text = 'NA';
            app.NAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NAViewField.Editable = 'off';
            app.NFALabel = uilabel(app.ScanparametersPanel);
            app.NFALabel.Text = 'NFA';
            app.NFAViewField = uieditfield(app.ScanparametersPanel, 'numeric');
            app.NFAViewField.Editable = 'off';
            app.FAdegEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.FAdegEditFieldLabel.Text = 'FA (deg)';
            app.FAViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.FAViewField.Editable = 'off';
            app.SequenceEditFieldLabel = uilabel(app.ScanparametersPanel);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.SequenceViewField.Editable = 'off';
            app.MRDfileLabel = uilabel(app.ScanparametersPanel);
            app.MRDfileLabel.Text = 'MRD file';
            app.MRDfileViewField = uieditfield(app.ScanparametersPanel, 'text');
            app.MRDfileViewField.Editable = 'off';
            app.MessagesPanel = uipanel(app.MRDRecoUIFigure);
            app.MessagesPanel.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesPanel);
            app.ReconstructionPanel = uipanel(app.MRDRecoUIFigure);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.RecoFig = uiaxes(app.ReconstructionPanel);
            title(app.RecoFig, '')
            xlabel(app.RecoFig, '')
            ylabel(app.RecoFig, '')
            app.RecoFig.XTick = [];
            app.RecoFig.YTick = [];
            app.SliceSlider = uislider(app.ReconstructionPanel);
            app.SliceSlider.Limits = [1 200];
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.MinorTicks = [];
            app.SliceSlider.Value = 1;
            app.EchoSlider = uislider(app.ReconstructionPanel);
            app.EchoSlider.Limits = [1 200];
            app.EchoSlider.MajorTicks = [];
            app.EchoSlider.MinorTicks = [];
            app.EchoSlider.Value = 1;
            app.RepetitionSlider = uislider(app.ReconstructionPanel);
            app.RepetitionSlider.Limits = [1 200];
            app.RepetitionSlider.MajorTicks = [];
            app.RepetitionSlider.MinorTicks = [];
            app.RepetitionSlider.Value = 1;
            app.XLabel_2 = uilabel(app.ReconstructionPanel);
            app.XLabel_2.Text = 'X';
            app.SliceEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.SliceEditField.Limits = [1 Inf];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.Value = 1;
            app.EchoLabel_2 = uilabel(app.ReconstructionPanel);
            app.EchoLabel_2.Text = 'Echo';
            app.EchoEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.EchoEditField.Limits = [1 Inf];
            app.EchoEditField.ValueDisplayFormat = '%.0f';
            app.EchoEditField.Value = 1;
            app.RepetitionEditFieldLabel_2 = uilabel(app.ReconstructionPanel);
            app.RepetitionEditFieldLabel_2.Text = 'Repetition';
            app.RepetitionEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.RepetitionEditField.Limits = [1 Inf];
            app.RepetitionEditField.ValueDisplayFormat = '%.0f';
            app.RepetitionEditField.Value = 1;
            app.OrientationSpinnerLabel_2 = uilabel(app.ReconstructionPanel);
            app.OrientationSpinnerLabel_2.Text = 'Orientation';
            app.OrientationSpinner = uispinner(app.ReconstructionPanel);
            app.OrientationSpinner.Limits = [1 6];
            app.OrientationSpinner.Value = 1;
            app.RXLabel = uilabel(app.ReconstructionPanel);
            app.RXLabel.Text = 'X';
            app.RYLabel = uilabel(app.ReconstructionPanel);
            app.RYLabel.Text = 'Y';
            app.TitleLabel = uilabel(app.MRDRecoUIFigure);
            app.TitleLabel.Text = 'MRD reconstructor';
            app.TulpImage = uiimage(app.MRDRecoUIFigure);
            % Show the figure after all components are created
            app.MRDRecoUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRDreco

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MRDRecoUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MRDRecoUIFigure)
        end
    end
end